classdef DatosApp_LISTO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        VovelButton      matlab.ui.control.Button
        RefrescarButton  matlab.ui.control.Button
        ExportarButton   matlab.ui.control.Button
        buscarButton     matlab.ui.control.Button
        EliminarButton   matlab.ui.control.Button
        AgregarButton    matlab.ui.control.Button
        CargarButton     matlab.ui.control.Button
        DatosTable       matlab.ui.control.Table
    end

    
    properties (Access = public)
        BuscarEditField  matlab.ui.control.EditField
    end
    
    properties (Access = private)
        conn % Description
        currentData % Para almacenar los datos actuales de la tabla
    end
    
    methods (Access = public)
        
        % Función para actualizar la tabla (añade esto)
       function actualizarTabla(app)
    try
        % Consulta con ordenamiento por código descendente (los nuevos primero)
        query = 'SELECT * FROM articulos ORDER BY codigo DESC LIMIT 100';
        
        % Ejecutar consulta
        curs = exec(app.conn, query);
        curs = fetch(curs);
        
        if ~isempty(curs.Data)
            app.DatosTable.Data = curs.Data;
        else
            uialert(app.UIFigure, 'La tabla de artículos está vacía', 'Información');
        end
    catch ME
        uialert(app.UIFigure, ['Error al actualizar: ' ME.message], 'Error', 'Icon', 'error');
    end
       end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            app.conn = conectarPostgres();
    
    % Consulta SQL
    data = fetch(app.conn, 'SELECT * FROM articulos');
    
    % Mostrar en la tabla
    app.DatosTable.Data = data;

    % Cerrar conexión
    close(app.conn);

        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            % Configuración del diálogo de entrada
    prompt = {
        'Código (ej: A011):', 
        'Nombre del producto:', 
        'ID Categoría (1-5):', 
        'Precio (ej: 100.00):', 
        'Cantidad:', 
        'ID Proveedor (1-5):'
    };
    dlgtitle = 'Nuevo Producto';
    dims = [1 50];
    definput = {'', '', '1', '0.00', '0', '1'};
    
    % Mostrar diálogo
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    
    if ~isempty(answer)
        try
            % Validaciones básicas
            if isempty(answer{1}) || isempty(answer{2})
                error('Código y Nombre son campos obligatorios');
            end
            
            % Verificar conexión activa
            if isempty(app.conn) || ~isvalid(app.conn)
                app.conn = conectarPostgres();
            end
            
            % Construir y ejecutar consulta INSERT con RETURNING
            query = sprintf([...
                'INSERT INTO articulos (codigo, nombre, id_categoria, precio, cantidad, id_proveedor) ', ...
                'VALUES (''%s'', ''%s'', %d, %.2f, %d, %d) ', ...
                'RETURNING *'], ...  % <-- Esto devuelve el registro insertado
                strrep(answer{1}, "'", "''"), ...
                strrep(answer{2}, "'", "''"), ...
                str2double(answer{3}), ...
                str2double(answer{4}), ...
                str2double(answer{5}), ...
                str2double(answer{6}));
            
            % Ejecutar consulta y obtener resultados
            curs = exec(app.conn, query);
            curs = fetch(curs);
            
            if ~isempty(curs.Data)
                % Confirmar éxito
                uialert(app.UIFigure, 'Producto agregado correctamente', 'Éxito', 'Icon', 'success');
                
                % Actualizar tabla mostrando el nuevo producto
                app.actualizarTabla(answer{1});
            else
                error('No se recibió confirmación de la base de datos');
            end
            
        catch ME
            uialert(app.UIFigure, ['Error: ' ME.message], 'Error', 'Icon', 'error');
            disp(getReport(ME));
        end
    end
    
        end

        % Button pushed function: buscarButton
        function buscarButtonPushed(app, event)
            filtro = app.BuscarEditField.Value;
            query = sprintf("SELECT * FROM articulos WHERE LOWER(nombre) LIKE '%%%s%%'", lower(filtro));
            data = fetch(app.conn, query);
            app.UITable.Data = data;
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
           try
        % 1. Establecer conexión si no existe
        if isempty(app.conn) || ~isvalid(app.conn)
            app.conn = conectarPostgres(); 
        end
        
        % 2. Crear diálogo para ingresar el código del producto a eliminar
        prompt = {'Ingrese el código del producto a eliminar:'};
        dlgtitle = 'Eliminar Producto';
        dims = [1 50];
        definput = {''};
        answer = inputdlg(prompt, dlgtitle, dims, definput);
        
        % 3. Verificar si se ingresó un código
        if isempty(answer) || isempty(answer{1})
            uialert(app.UIFigure, 'No se ingresó ningún código', 'Advertencia', 'Icon', 'warning');
            return;
        end
        
        codigo = strtrim(answer{1});
        
        % 4. Verificar si el producto existe
        queryVerificar = ['SELECT codigo, nombre, precio, cantidad FROM articulos WHERE codigo = ''' codigo ''''];
        producto = fetch(app.conn, queryVerificar);
        
        if isempty(producto)
            uialert(app.UIFigure, sprintf('No existe un producto con código: %s', codigo), ...
                   'Producto no encontrado', 'Icon', 'warning');
            return;
        end
        
        % 5. Mostrar confirmación con detalles del producto
        mensaje = sprintf(['¿Está seguro que desea eliminar este producto?\n\n'...
                         '• Código: %s\n'...
                         '• Nombre: %s\n'...
                         '• Precio: $%.2f\n'...
                         '• Cantidad: %d'], ...
                         producto.codigo{1}, ...
                         producto.nombre{1}, ...
                         producto.precio, ...
                         producto.cantidad);
        
        seleccion = uiconfirm(app.UIFigure, mensaje, 'Confirmar Eliminación', ...
                             'Options', {'Eliminar', 'Cancelar'}, ...
                             'DefaultOption', 2, 'CancelOption', 2, ...
                             'Icon', 'warning');
        
        if strcmp(seleccion, 'Cancelar')
            return;
        end
        
        % 6. Ejecutar eliminación - CORRECCIÓN AQUÍ: usar execute en lugar de ejecutar
        queryEliminar = ['DELETE FROM articulos WHERE codigo = ''' codigo ''''];
        execute(app.conn, queryEliminar);  % Cambiado a execute
        
        % 7. Verificar que se eliminó correctamente
        productoVerificado = fetch(app.conn, queryVerificar);
        
        if isempty(productoVerificado)
            mensajeExito = sprintf('Producto eliminado correctamente:\n\n%s - %s', ...
                                  producto.codigo{1}, producto.nombre{1});
            uialert(app.UIFigure, mensajeExito, 'Éxito', 'Icon', 'success');
            
            % Opcional: Actualizar tabla o lista de productos en la app
            if isprop(app, 'TablaProductos')
                actualizarTablaProductos(app);
            end
        else
            error('El producto no pudo ser eliminado');
        end
        
    catch ME
        errorMsg = sprintf('❌ Error al eliminar producto:\n%s\n\nDetalles técnicos:\n%s', ...
                         ME.message, getReport(ME, 'basic'));
        uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
    end
end

% Función auxiliar para actualizar la tabla (si existe)
function actualizarTablaProductos(app)
    try
        query = 'SELECT codigo, nombre, precio, cantidad FROM articulos ORDER BY codigo';
        datos = fetch(app.conn, query);
        app.TablaProductos.Data = datos;
    catch
        % Si falla la actualización, no hacer nada
    end

    
        end

        % Button pushed function: RefrescarButton
        function RefrescarButtonPushed(app, event)
              try
        % Verificar si existe la tabla en la interfaz
        if ~isprop(app, 'TablaProductos') || isempty(app.TablaProductos)
            error('La tabla de productos no está disponible en la interfaz');
        end
        
        % Consulta SQL
        query = ['SELECT codigo, nombre, precio, cantidad ' ...
                'FROM articulos ' ...
                'ORDER BY codigo'];
        
        % Ejecutar consulta
        datos = fetch(app.conn, query);
        
        % Actualizar UI
        if ~isempty(datos)
            app.TablaProductos.Data = datos;
            
            % Opcional: Formatear columnas
            if isprop(app.TablaProductos, 'ColumnFormat')
                app.TablaProductos.ColumnFormat = {
                    'char',    % Código
                    'char',    % Nombre
                    'bank',    % Precio (formato monetario)
                    'numeric'  % Cantidad
                };
            end
        else
            % Si no hay datos, limpiar tabla
            app.TablaProductos.Data = cell(0, 4);
        end
        
    catch ME
        rethrow(ME); % Relanza el error para manejarlo en la función principal
    end
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            % Verificar si hay datos para exportar
    if isempty(app.DatosTable.Data)
        uialert(app.UIFigure, 'No hay datos para exportar', 'Advertencia', 'Icon', 'warning');
        return;
    end
    
    % Crear diálogo para guardar archivo
    [filename, pathname] = uiputfile(...
        {'*.xlsx', 'Libro de Excel (*.xlsx)'; 
         '*.csv', 'Archivo CSV (*.csv)'}, ...
        'Guardar como');
    
    % Si el usuario no cancela la operación
    if ~isequal(filename, 0)
        % Construir la ruta completa del archivo
        fullpath = fullfile(pathname, filename);
        
        try
            % Obtener los datos de la tabla
            tableData = app.DatosTable.Data;
            
            % Determinar si necesitamos convertir a tabla MATLAB
            if ~istable(tableData) && ~ismatrix(tableData)
                % Convertir cell array a tabla
                columnNames = app.DatosTable.ColumnName;
                if isempty(columnNames)
                    columnNames = arrayfun(@(x) sprintf('Columna%d', x), ...
                                        1:size(tableData, 2), 'UniformOutput', false);
                end
                
                % Crear tabla MATLAB
                exportData = cell2table(tableData, 'VariableNames', columnNames);
            else
                exportData = tableData;
            end
            
            % Escribir el archivo según la extensión
            [~, ~, ext] = fileparts(filename);
            switch lower(ext)
                case '.xlsx'
                    writetable(exportData, fullpath, 'WriteMode', 'overwritesheet', ...
                              'PreserveFormat', true);
                case '.csv'
                    writetable(exportData, fullpath);
                otherwise
                    writetable(exportData, fullpath);
            end
            
            % Mostrar mensaje de éxito
            uialert(app.UIFigure, sprintf('Datos exportados exitosamente a:\n%s', fullpath), ...
                   'Exportación completada', 'Icon', 'success');
            
        catch ME
            % Manejo de errores
            errorMsg = sprintf('Error al exportar:\n%s', ME.message);
            uialert(app.UIFigure, errorMsg, 'Error de exportación', 'Icon', 'error');
        end
    end
        end

        % Button pushed function: VovelButton
        function VovelButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure, ...
        '¿Desea volver al Menú?', ...  % Mensaje
        'Confirmar salida', ...                                 % Título
        'Options', {'Sí', 'No'}, ...                            % Botones
        'DefaultOption', 2, ...                                 % Opción por defecto: No
        'CancelOption', 2);  
         if strcmp(selection, 'Sí')
            MainMenuApp;
         end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatosTable = uitable(app.UIFigure);
            app.DatosTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DatosTable.RowName = {};
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Gestion de Inventario';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.buscarButton = uibutton(app.UIFigure, 'push');
            app.buscarButton.ButtonPushedFcn = createCallbackFcn(app, @buscarButtonPushed, true);
            app.buscarButton.Text = 'buscar';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            app.RefrescarButton = uibutton(app.UIFigure, 'push');
            app.RefrescarButton.ButtonPushedFcn = createCallbackFcn(app, @RefrescarButtonPushed, true);
            app.RefrescarButton.Text = 'Refrescar';
            app.VovelButton = uibutton(app.UIFigure, 'push');
            app.VovelButton.ButtonPushedFcn = createCallbackFcn(app, @VovelButtonPushed, true);
            app.VovelButton.Text = 'Vovel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatosApp_LISTO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end