classdef LoginApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        IniciarSesionButton  matlab.ui.control.Button
        ContraseLabel        matlab.ui.control.Label
        UsuarioLabel         matlab.ui.control.Label
        ContrasenaEditField  matlab.ui.control.EditField
        UsuarioEditField     matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarSesionButton
        function IniciarSesionButtonPushed(app, event)
                usuario = app.UsuarioEditField.Value;
    contrasena = app.ContrasenaEditField.Value;
    try
        if verificarCredenciales(usuario, contrasena)
            uialert(app.UIFigure, 'Inicio de sesión exitoso.', 'Bienvenido');
            pause(1);
            close(app.UIFigure);
            MainMenuApp;  % abrir la siguiente ventana
        else
            uialert(app.UIFigure, 'Usuario o contraseña incorrectos.', 'Error');
        end
    catch ME
        uialert(app.UIFigure, sprintf("Error al conectar: %s", ME.message), 'Error de conexión');
    end
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContrasenaEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'Usuario';
            app.ContraseLabel = uilabel(app.UIFigure);
            app.ContraseLabel.Text = 'Contraseña';
            app.IniciarSesionButton = uibutton(app.UIFigure, 'push');
            app.IniciarSesionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarSesionButtonPushed, true);
            app.IniciarSesionButton.Text = 'Iniciar Sesion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end